classdef Rgrains < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RgrainsUIFigure                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadsettingsMenu               matlab.ui.container.Menu
        SavesettingsMenu               matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        AcquisitionMenu                matlab.ui.container.Menu
        imagetypeMenu                  matlab.ui.container.Menu
        jpgMenu                        matlab.ui.container.Menu
        tifMenu                        matlab.ui.container.Menu
        pngMenu                        matlab.ui.container.Menu
        BinarisationMenu               matlab.ui.container.Menu
        UpconvertMenu                  matlab.ui.container.Menu
        IgnoreparticlesonborderMenu    matlab.ui.container.Menu
        AnalysisMenu                   matlab.ui.container.Menu
        ApplyPCDnormarisationMenu      matlab.ui.container.Menu
        CalcRoundnessMenu              matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        SavecsvMenu                    matlab.ui.container.Menu
        SaveBWimageMenu                matlab.ui.container.Menu
        SavesummaryimageMenu           matlab.ui.container.Menu
        SavefittedimagewithNumberMenu  matlab.ui.container.Menu
        SavefittedimagewithRoundnessMenu  matlab.ui.container.Menu
        SavefittredimageasvectorMenu   matlab.ui.container.Menu
        AutoSavesettingsMenu           matlab.ui.container.Menu
        BatchMenu                      matlab.ui.container.Menu
        RunBatch                       matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        MergetableMenu                 matlab.ui.container.Menu
        OthersMenu                     matlab.ui.container.Menu
        VersionMenu                    matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LoadimageButton                matlab.ui.control.Button
        BinariseButton                 matlab.ui.control.Button
        AnalyseButton                  matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        ParticleColour                 matlab.ui.control.DropDown
        BinariseMethod                 matlab.ui.control.DropDown
        BinariseSensitivity            matlab.ui.control.NumericEditField
        ImageScale                     matlab.ui.control.NumericEditField
        NoiseThresholdLower            matlab.ui.control.NumericEditField
        NoiseThresholdUpper            matlab.ui.control.NumericEditField
        Traceprecision                 matlab.ui.control.NumericEditField
        Cornersensitivity              matlab.ui.control.NumericEditField
        Circlerecision                 matlab.ui.control.NumericEditField
        PCDthreshold                   matlab.ui.control.NumericEditField
        PCDthresholdpixEditFieldLabel  matlab.ui.control.Label
        CircleprecisionLabel           matlab.ui.control.Label
        CornersensitivityEditFieldLabel  matlab.ui.control.Label
        TraceprecisionLabel            matlab.ui.control.Label
        ImagescalepixcmEditFieldLabel  matlab.ui.control.Label
        SensitivityEditFieldLabel      matlab.ui.control.Label
        MethodDropDownLabel            matlab.ui.control.Label
        ParticlecolourDropDownLabel    matlab.ui.control.Label
        AcquisitionLabel               matlab.ui.control.Label
        ExportLabel                    matlab.ui.control.Label
        AnalysisLabel                  matlab.ui.control.Label
        BinarisationLabel              matlab.ui.control.Label
        NoiseThresholdLabel            matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        CurrentImageLabel              matlab.ui.control.Label
        ViewImage                      matlab.ui.control.DropDown
        MainAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        rgrains       = rcalculator();
        opts_app       = struct('base_dir',[],...
                                'in_image_type','jpg');
        opts_rgrains   = struct('version','v5.0.2 (2024 Mar 6)');
        opts_binarise  = struct('upconvert',false,...
                                'particle_color', 'Dark',...
                                'method','Adaptive',...
                                'adaptive_sensitivity',0.35,...
                                'noise_thresholds',[490, Inf],...
                                'ignore_particles_on_borders',true);
        opts_roundness = struct( 'calc_roundness',true,...
                                'trace_precision',0.060,...%span
                                'corner_sensitivity',0.017,...%tol
                                'circle_precision',0.996,...%factor
                                'image_scale',340,...%real scale of image[pix/cm]
                                'PCD_normarisation',true,...%size normarising by PCD
                                'PCD_size',300);%target size of PCD
        opts_plot      = struct('base_image','original',... %['original', 'bw']
                                'colour_smoothed_particle_boundaries','green',...
                                'colour_max_inscribed_circle','red',...
                                'colour_corner_circles','blue',...
                                'colour_info_text','magenta',...
                                'font','Arial',...
                                'plot_info','Particlenumber'); %['Particlenumber', 'Roundness']
        opts_export    = struct('save_bw_image',true,...
                                'save_fitted_image_with_No',true,...
                                'save_fitted_image_vector',false,...
                                'save_fitted_image_with_Roundness',true,...
                                'save_summary_image',true,...
                                'save_csv',true,...
                                'save_settings',false);
    end
    

    methods (Access = private)
        
        function startupFun(app)

            %set requirement
            rq_tools = {'MATLAB';...
                        'Curve Fitting Toolbox';...
                        'Image Processing Toolbox';...
                        'Statistics and Machine Learning Toolbox';...
                        'Computer Vision Toolbox'};
            ver_matlab = '9.12';%2022a
            
            %set warning
            warning('off','all');

            %check matlab version
            disp('~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~')
            disp(strcat('Starting "',app.version,'"'));
            fprintf('Checking running system...');
            if isdeployed == 1
                fprintf('SKIP: Rgrains is launched from executive file.\n')
            else
                V = verLessThan('MATLAB',ver_matlab );          
                if V==0
                    fprintf('OK\n');
                else
                    fprintf('CAUTION: Version of Matlab is earlier than requirement.\n');
                end
                
                %chek toolbox
                fprintf('Checking toolboxes...');
                inst_tool = struct2table(ver);
                c = find(ismember(rq_tools, inst_tool{:,1})==0);
                
                if isempty(c)
                    fprintf('OK\n');
                else
                    fprintf('CAUTION: The following requirement tool(s) is not installed. \n');
                    for i=1:numel(c)
                        disp(rq_tools{c(i),1});
                    end
                end
                
            end
            
            disp('~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~')   

            %for next step
            app.BinariseButton.Enable = false;
            app.AnalyseButton.Enable = false;
            app.ExportButton.Enable = false;
        end
    
        function updateDiplaySettings(app)
            %update app display
            app.ParticleColour.Value      = app.opts_binarise.particle_color;
            app.BinariseMethod.Value      = app.opts_binarise.method;
            app.BinariseSensitivity.Value = app.opts_binarise.adaptive_sensitivity;
            app.NoiseThresholdLower.Value = app.opts_binarise.noise_thresholds(1);
            app.NoiseThresholdUpper.Value = app.opts_binarise.noise_thresholds(2);
            if app.opts_binarise.ignore_particles_on_borders == true
                app.IgnoreparticlesonborderMenu.Checked = "on";
            else
                app.IgnoreparticlesonborderMenu.Checked = "off";
            end
            if app.opts_binarise.upconvert ==true
                app.UpconvertMenu.Checked = "on";
            else
                app.UpconvertMenu.Checked = "off";
            end
        
            app.Traceprecision.Value    = app.opts_roundness.trace_precision;
            app.Cornersensitivity.Value = app.opts_roundness.corner_sensitivity;
            app.Circlerecision.Value    = app.opts_roundness.circle_precision;
            app.ImageScale.Value        = app.opts_roundness.image_scale;
            app.PCDthreshold.Value      = app.opts_roundness.PCD_size;
            if app.opts_roundness.PCD_normarisation==true
                app.ApplyPCDnormarisationMenu.Checked = "on";
            else
                app.ApplyPCDnormarisationMenu.Checked = "off";
            end
            if app.opts_roundness.calc_roundness==true
                app.CalcRoundnessMenu.Checked = 'on';
            else
                app.CalcRoundnessMenu.Checked = 'off';
            end


            if app.opts_export.save_bw_image==true
                app.SaveBWimageMenu.Checked = "on";
            else
                app.SaveBWimageMenu.Checked = "off";
            end
            if app.opts_export.save_fitted_image_with_No==true
                app.SavefittedimagewithNumberMenu.Checked = "on";
            else
                app.SavefittedimagewithNumberMenu.Checked = "off";
            end
            if app.opts_export.save_fitted_image_vector==true
                app.SavefittredimageasvectorMenu.Checked = "on";
            else
                app.SavefittredimageasvectorMenu.Checked = "off";
            end
            if app.opts_export.save_fitted_image_with_Roundness==true
                app.SavefittedimagewithRoundnessMenu.Checked = "on";
            else
                app.SavefittedimagewithRoundnessMenu.Checked = "off";
            end
            if app.opts_export.save_summary_image==true
                app.SavesummaryimageMenu.Checked = "on";
            else
                app.SavesummaryimageMenu.Checked = "off";
            end
            if app.opts_export.save_csv==true
                app.SavecsvMenu.Checked = "on";
            else
                app.SavecsvMenu.Checked = "off";
            end
            if app.opts_export.save_settings==true
                app.SavesettingsMenu.Checked = "on";
            else
                app.SavesettingsMenu.Checked = "off";
            end

            %app.opts_plot.base_image;
            %app.opts_plot.colour_smoothed_particle_boundaries
            %app.opts_plot.colour_max_inscribed_circle
            %app.opts_plot.colour_corner_circles
            %app.opts_plot.colour_info_text
            %app.opts_plot.font
            %app.opts_plot.plot_info
        end
    
        function updatePropertySettings(app)
            %update app display
            app.opts_binarise.particle_color       = app.ParticleColour.Value;
            app.opts_binarise.method               = app.BinariseMethod.Value;
            app.opts_binarise.adaptive_sensitivity = app.BinariseSensitivity.Value;
            app.opts_binarise.noise_thresholds     = [app.NoiseThresholdLower.Value, app.NoiseThresholdUpper.Value];
            switch app.IgnoreparticlesonborderMenu.Checked
                case 'on'
                    app.opts_binarise.ignore_particles_on_borders = true;
                case 'off'
                    app.opts_binarise.ignore_particles_on_borders = false;
            end

            switch app.UpconvertMenu.Checked
                case 'on'
                    app.opts_binarise.upconvert = true;
                case 'off'
                    app.opts_binarise.upconvert = false;
            end

            app.opts_roundness.trace_precision    = app.Traceprecision.Value;
            app.opts_roundness.corner_sensitivity = app.Cornersensitivity.Value;
            app.opts_roundness.circle_precision   = app.Circlerecision.Value;
            app.opts_roundness.image_scale        = app.ImageScale.Value;
            app.opts_roundness.PCD_size           = app.PCDthreshold.Value;
            switch  app.ApplyPCDnormarisationMenu.Checked
                case 'on'
                    app.opts_roundness.PCD_normarisation = true;
                case 'off'
                    app.opts_roundness.PCD_normarisation = false;
            end
            switch  app.CalcRoundnessMenu.Checked
                case 'on'
                    app.opts_roundness.calc_roundness = true;
                case 'off'
                    app.opts_roundness.calc_roundness = false;
            end

            switch app.SaveBWimageMenu.Checked
                case 'on'
                    app.opts_export.save_bw_image = true;
                case 'off'
                    app.opts_export.save_bw_image = false;
            end
            switch app.SavefittedimagewithNumberMenu.Checked
                case 'on'
                    app.opts_export.save_fitted_image_with_No = true;
                case 'off'
                    app.opts_export.save_fitted_image_with_No = false;
            end
            switch app.SavefittredimageasvectorMenu.Checked
                case 'on'
                    app.opts_export.save_fitted_image_vector = true;
                case 'off'
                    app.opts_export.save_fitted_image_vector = false;
            end
            switch app.SavefittedimagewithRoundnessMenu.Checked
                case 'on'
                    app.opts_export.save_fitted_image_with_Roundness = true;
                case 'off'
                    app.opts_export.save_fitted_image_with_Roundness = false;
            end
            switch app.SavesummaryimageMenu.Checked
                case 'on'
                    app.opts_export.save_summary_image = true;
                case 'off'
                    app.opts_export.save_summary_image = false;
            end
            switch app.SavecsvMenu.Checked
                case 'on'
                    app.opts_export.save_csv = true;
                case 'off'
                    app.opts_export.save_csv = false;
            end
            switch app.SavesettingsMenu.Checked
                case 'on'
                    app.opts_export.save_settings = true;
                case 'off'
                    app.opts_export.save_settings = false;
            end

            %app.opts_plot.base_image;
            %app.opts_plot.colour_smoothed_particle_boundaries
            %app.opts_plot.colour_max_inscribed_circle
            %app.opts_plot.colour_corner_circles
            %app.opts_plot.colour_info_text
            %app.opts_plot.font
            %app.opts_plot.plot_info
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %check new release
            url = 'https://api.github.com/repos/keitaroyamada/Rgrains/releases/latest';

            try
                curVer = split(app.opts_rgrains.version,{' ','v','.'});
                releaseInfo = webread(url);
                webVer = split(releaseInfo.tag_name,{'v','.'});
                
                isLatest = true;
                if str2double(webVer{2})>str2double(curVer{2})
                    isLatest = false;
                elseif str2double(webVer{3})>str2double(curVer{3})
                    isLatest = false;
                elseif str2double(webVer{4})>str2double(curVer{4})
                    isLatest = false;
                end
                
                if isLatest==false
                    q = uiconfirm(app.RgrainsUIFigure,strcat("New version (",releaseInfo.tag_name,") released. "),"Update checker","Options",{'Get','Afterward'})
                    if q=="Get"
                        web('https://github.com/keitaroyamada/Rgrains/releases/', '-browser');
                    end
                else
                    disp("Latest version is installed.")
                end
            catch e
                disp(strcat('Update check failed.'));%,e.message
            end
        end

        % Menu selected function: LoadsettingsMenu
        function LoadsettingsMenuSelected(app, event)
            [n, p] = uigetfile(fullfile(app.opts_app.base_dir,'*.rgrains'));

            load(fullfile(p, n),"-mat");

            app.opts_binarise  = opts_binarise;
            app.opts_export    = opts_export;
            app.opts_plot      = opts_plot;
            app.opts_roundness = opts_roundness;
            app.opts_rgrains   = opts_rgrains;

            app.updateDiplaySettings();
        end

        % Menu selected function: SavesettingsMenu
        function SavesettingsMenuSelected(app, event)
            [n, p] = uiputfile(fullfile(app.opts_app.base_dir,'RgrainsSettings.rgrains'));

            app.updatePropertySettings();
            opts_rgrains   = app.opts_rgrains;
            opts_binarise  = app.opts_binarise;
            opts_roundness = app.opts_roundness;
            opts_plot      = app.opts_plot;
            opts_export    = app.opts_export

            save(fullfile(p, n),'opts_rgrains', 'opts_binarise', 'opts_roundness', 'opts_plot', 'opts_export');

        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            if isempty(app.rgrains.rprops)==0
                %case instance contains calculation results
                selection = uiconfirm(app.RgrainsUIFigure, 'Unsaved calculation results will be deleted. Do you want to proceed?', 'CAUTION','Icon','warning');
                switch selection
                    case 'Cancel'
                        return;
                end
            end

            %initiarise & make new instance
            app.rgrains = rcalculator();
            cla(app.MainAxes);

            app.updatePropertySettings();
            app.rgrains.opts_binarise = app.opts_binarise;%submit properties to rcalculator
            
            %get image path 
            [im_n, im_p] = uigetfile(fullfile(app.opts_app.base_dir,strcat('*.',app.opts_app.in_image_type)));
            if im_n ==0
                return
            end
            app.opts_app.base_dir = im_p;
            [~, im_name, ~] = fileparts(im_n);

            %load image
            app.rgrains.loadImage(fullfile(im_p, im_n));
            app.rgrains.im_name = im_name;

            %show image
            imshow(app.rgrains.im_in,'Parent',app.MainAxes);
            app.ViewImage.Value = 'Original';
            app.CurrentImageLabel.Text = im_name;

            %for next step
            app.BinariseButton.Enable = true;
            app.AnalyseButton.Enable = false;
            app.ExportButton.Enable = false;

        end

        % Button pushed function: BinariseButton
        function BinariseButtonPushed(app, event)
            %binarise
            app.updatePropertySettings();%update app properties
            app.rgrains.opts_binarise = app.opts_binarise;%submit properties to rcalculator
            app.rgrains.opts_binarise
            app.rgrains.binariseImage();%binarising by rcalculator

            %update plot
            app.ViewImage.Value = 'BW';
            imshow(app.rgrains.im_bw,'Parent',app.MainAxes);

            %for next step
            app.BinariseButton.Enable = true;
            app.AnalyseButton.Enable = true;
            app.ExportButton.Enable = false;
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            %calculate roundness
            app.updatePropertySettings();%update app properties
            app.rgrains.opts_roundness = app.opts_roundness;%submit properties to rcalculator
            app.rgrains.opts_roundness
            warning('off','all')
            app.rgrains.calcRoundness(app.RgrainsUIFigure);
            warning('on','all')

            %update plot
            app.ViewImage.Value = 'Results';
            app.rgrains.makeResultImage(app.MainAxes);

            %for next step
            app.BinariseButton.Enable = true;
            app.AnalyseButton.Enable = true;
            app.ExportButton.Enable = true;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            save_dir = uigetdir(app.opts_app.base_dir);
            if save_dir==0
                return
            end
            
            %export results
            app.updatePropertySettings();%update app properties
            app.rgrains.opts_export = app.opts_export;%submit properties to rcalculator
            app.rgrains.opts_export

            app.rgrains.export(save_dir,app.RgrainsUIFigure);
        end

        % Menu selected function: RunBatch
        function RunBatchMenuSelected(app, event)
            %get image directory
            im_dir = uigetdir(app.opts_app.base_dir,strcat('Please chose [',app.opts_app.in_image_type, '] image folder'));
            if im_dir ==0
                return
            end
            app.opts_app.base_dir = im_dir;

            %get save directory
            save_dir = uigetdir(app.opts_app.base_dir,'Please chose SAVE folder');
            if save_dir ==0
                return
            end

            %get image list
            im_list = dir(fullfile(im_dir, strcat('*.',app.opts_app.in_image_type)));
            if size(im_list,1)==0
                uiconfirm(app.RgrainsUIFigure, {'There is no image. Please chose image folder.';'Tips: Image type can be changed from settings.'},'Confirmation','Icon','warning');
                return
            end
        
            d = uiprogressdlg(app.RgrainsUIFigure,'Title','Now processing...','Cancelable','on');
            disp('Batch process started.')
            disp('-------------------------------------------------------');
            disp('-------------------------------------------------------');
            tic;
            lap_time = zeros(size(im_list,1),1);
            for i =1: size(im_list,1)
                if d.CancelRequested
                    selection = uiconfirm(app.RgrainsUIFigure,'Cancel process?','Confirm Close','Icon','warning');
                    switch selection
                        case'OK'
                            break
                        case 'cancel'
                    end
                end

                d.Message = 'Initiarizing...';
                %make new instance
                app.rgrains = rcalculator();

                %submit properties to rcalculator
                app.updatePropertySettings();
                app.rgrains.opts_binarise  = app.opts_binarise
                app.rgrains.opts_roundness = app.opts_roundness;
                app.rgrains.opts_plot      = app.opts_plot;
                app.rgrains.opts_export    = app.opts_export;

                %get image path
                im_path = fullfile(im_dir, im_list(i).name);
                [~,app.rgrains.im_name,~]=fileparts(im_list(i).name);
                
                disp(strcat('[',num2str(i),'/',num2str(size(im_list,1)),']:',app.rgrains.im_name))
                d.Message =strcat('[',num2str(i),'/',num2str(size(im_list,1)),']','Calculating roundness...',app.rgrains.im_name);

                %1. load image
                app.rgrains.loadImage(im_path);

                %2. binarise
                app.rgrains.binariseImage();

                %3. calc roundness
                app.rgrains.calcRoundness([]);

                %4. export results
                app.rgrains.export(save_dir);

                %progressbar
                d.Value = (i / size(im_list,1));
                RT = HMS_m((size(im_list,1)-i) * (toc/i));
                d.Title = strcat('Calculating roundness(remaining time:',num2str(RT(1)),'h',num2str(RT(2)),'m',num2str(round(RT(3))),'s)');

                lap_time(i) = toc - sum(lap_time(1:i-1)); 
                fprintf(strcat('Lap time:',num2str(round(lap_time(i)/60,2)),'min\n'));
                disp('-------------------------------------------------------');
            end
            disp('-------------------------------------------------------');
            disp('Batch process finished.')

            %save settings
            opts_rgrains   = app.opts_rgrains;
            opts_binarise  = app.opts_binarise;
            opts_roundness = app.opts_roundness;
            opts_plot      = app.opts_plot;
            opts_export    = app.opts_export;

            save(fullfile(save_dir,'Rgrains_settings.rgrains'),'opts_rgrains', 'opts_binarise', 'opts_roundness', 'opts_plot', 'opts_export');
            
            %close waitbar;
            close(d);
            RT = HMS_m(toc);
            ms = strcat('All images have been analysed. Total processing time:',num2str(RT(1)),'h',num2str(RT(2)),'m',num2str(round(RT(3))),'s');
            uialert(app.RgrainsUIFigure,'Finish all process',ms,'Icon','success');
            ms = strcat('Total processing time:',num2str(RT(1)),'h',num2str(RT(2)),'m',num2str(round(RT(3))),'s');
            disp(ms);
            beep;
        end

        % Menu selected function: UpconvertMenu
        function UpconvertMenuSelected(app, event)
            switch app.UpconvertMenu.Checked
                case 'off'
                    app.UpconvertMenu.Checked = 'on';
                case 'on'
                    app.UpconvertMenu.Checked = 'off';
            end
        end

        % Menu selected function: IgnoreparticlesonborderMenu
        function IgnoreparticlesonborderMenuSelected(app, event)
            switch app.IgnoreparticlesonborderMenu.Checked
                case 'off'
                    app.IgnoreparticlesonborderMenu.Checked = 'on';
                case 'on'
                    app.IgnoreparticlesonborderMenu.Checked = 'off';
            end
        end

        % Menu selected function: ApplyPCDnormarisationMenu
        function ApplyPCDnormarisationMenuSelected(app, event)
            switch app.ApplyPCDnormarisationMenu.Checked
                case 'off'
                    app.ApplyPCDnormarisationMenu.Checked = 'on';
                    app.PCDthreshold.Enable = true;
                case 'on'
                    app.ApplyPCDnormarisationMenu.Checked = 'off';
                    app.PCDthreshold.Enable = false;
            end
        end

        % Menu selected function: CalcRoundnessMenu
        function CalcRoundnessMenuSelected(app, event)
            switch app.CalcRoundnessMenu.Checked
                case 'off'
                    app.CalcRoundnessMenu.Checked = 'on';
                case 'on'
                    app.CalcRoundnessMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavecsvMenu
        function SavecsvMenuSelected(app, event)
            switch app.SavecsvMenu.Checked
                case 'off'
                    app.SavecsvMenu.Checked = 'on';
                case 'on'
                    app.SavecsvMenu.Checked = 'off';
            end
        end

        % Menu selected function: SaveBWimageMenu
        function SaveBWimageMenuSelected(app, event)
            switch app.SaveBWimageMenu.Checked
                case 'off'
                    app.SaveBWimageMenu.Checked = 'on';
                case 'on'
                    app.SaveBWimageMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavesummaryimageMenu
        function SavesummaryimageMenuSelected(app, event)
            switch app.SavesummaryimageMenu.Checked
                case 'off'
                    app.SavesummaryimageMenu.Checked = 'on';
                case 'on'
                    app.SavesummaryimageMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavefittedimagewithNumberMenu
        function SavefittedimagewithNumberMenuSelected(app, event)
            switch app.SavefittedimagewithNumberMenu.Checked
                case 'off'
                    app.SavefittedimagewithNumberMenu.Checked = 'on';
                case 'on'
                    app.SavefittedimagewithNumberMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavefittedimagewithRoundnessMenu
        function SavefittedimagewithRoundnessMenuSelected(app, event)
            switch app.SavefittedimagewithRoundnessMenu.Checked
                case 'off'
                    app.SavefittedimagewithRoundnessMenu.Checked = 'on';
                case 'on'
                    app.SavefittedimagewithRoundnessMenu.Checked = 'off';
            end
        end

        % Menu selected function: SavefittredimageasvectorMenu
        function SavefittredimageasvectorMenuSelected(app, event)
            switch app.SavefittredimageasvectorMenu.Checked
                case 'off'
                    app.SavefittredimageasvectorMenu.Checked = 'on';
                case 'on'
                    app.SavefittredimageasvectorMenu.Checked = 'off';
            end
        end

        % Menu selected function: AutoSavesettingsMenu
        function AutoSavesettingsMenuSelected(app, event)
            switch app.AutoSavesettingsMenu.Checked
                case 'off'
                    app.AutoSavesettingsMenu.Checked = 'on';
                case 'on'
                    app.AutoSavesettingsMenu.Checked = 'off';
            end
        end

        % Menu selected function: jpgMenu
        function jpgMenuSelected(app, event)
            switch app.jpgMenu.Checked
                case 'off'
                    app.jpgMenu.Checked = 'on';
                    app.tifMenu.Checked = 'off';
                    app.pngMenu.Checked = 'off';
                    app.opts_app.in_image_type = 'jpg';
            end
        end

        % Menu selected function: tifMenu
        function tifMenuSelected(app, event)
            switch app.jpgMenu.Checked
                case 'off'
                    app.jpgMenu.Checked = 'off';
                    app.tifMenu.Checked = 'on';
                    app.pngMenu.Checked = 'off';
                    app.opts_app.in_image_type = 'tif';
            end
        end

        % Menu selected function: pngMenu
        function pngMenuSelected(app, event)
            switch app.jpgMenu.Checked
                case 'off'
                    app.jpgMenu.Checked = 'off';
                    app.tifMenu.Checked = 'off';
                    app.pngMenu.Checked = 'on';
                    app.opts_app.in_image_type = 'png';
            end
        end

        % Value changed function: ViewImage
        function ViewImageValueChanged(app, event)
            value = app.ViewImage.Value;
            switch value
                case 'Original'
                    if isempty(app.rgrains.im_in)==0
                        imshow(app.rgrains.im_in, 'Parent',app.MainAxes);
                    else
                        app.ViewImage.Value = 'Original';
                        imshow([], 'Parent',app.MainAxes);
                    end
                case 'BW'
                    if isempty(app.rgrains.im_bw)==0
                        imshow(app.rgrains.im_bw, 'Parent',app.MainAxes);
                    else
                        app.ViewImage.Value = 'Original';
                        imshow(app.rgrains.im_in, 'Parent',app.MainAxes);
                    end
                case 'Results'
                    if isempty(app.rgrains.rprops)==0
                        app.rgrains.makeResultImage(app.MainAxes)
                    else
                        app.ViewImage.Value = 'Original';
                        imshow(app.rgrains.im_in, 'Parent',app.MainAxes);
                    end
            end
        end

        % Menu selected function: VersionMenu
        function VersionMenuSelected(app, event)
            txt = strcat({'Rgrains version:',app.opts_rgrains.version})
            uialert(app.RgrainsUIFigure, txt,'Version info','Icon','info');
        end

        % Menu selected function: MergetableMenu
        function MergetableMenuSelected(app, event)
            %call tool
            Rgrains_merge           
        end

        % Value changed function: BinariseMethod
        function BinariseMethodValueChanged(app, event)
            value = app.BinariseMethod.Value;
            
            switch value
                case "Adaptive"
                    app.BinariseSensitivity.Enable = true;
                case "Otsu"
                    app.BinariseSensitivity.Enable = false;
                case "None"
                    app.BinariseSensitivity.Enable = false;

            end
                
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RgrainsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RgrainsUIFigure = uifigure('Visible', 'off');
            app.RgrainsUIFigure.Name = 'Rgrains';
            app.RgrainsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.RgrainsUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadsettingsMenu = uimenu(app.FileMenu);
            app.LoadsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsettingsMenuSelected, true);
            app.LoadsettingsMenu.Text = 'Load settings';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesettingsMenuSelected, true);
            app.SavesettingsMenu.Text = 'Save settings';
            app.SettingsMenu = uimenu(app.RgrainsUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AcquisitionMenu = uimenu(app.SettingsMenu);
            app.AcquisitionMenu.Text = 'Acquisition';
            app.imagetypeMenu = uimenu(app.AcquisitionMenu);
            app.imagetypeMenu.Text = 'image type';
            app.jpgMenu = uimenu(app.imagetypeMenu);
            app.jpgMenu.MenuSelectedFcn = createCallbackFcn(app, @jpgMenuSelected, true);
            app.jpgMenu.Checked = 'on';
            app.jpgMenu.Text = 'jpg';
            app.tifMenu = uimenu(app.imagetypeMenu);
            app.tifMenu.MenuSelectedFcn = createCallbackFcn(app, @tifMenuSelected, true);
            app.tifMenu.Text = 'tif';
            app.pngMenu = uimenu(app.imagetypeMenu);
            app.pngMenu.MenuSelectedFcn = createCallbackFcn(app, @pngMenuSelected, true);
            app.pngMenu.Text = 'png';
            app.BinarisationMenu = uimenu(app.SettingsMenu);
            app.BinarisationMenu.Text = 'Binarisation';
            app.UpconvertMenu = uimenu(app.BinarisationMenu);
            app.UpconvertMenu.MenuSelectedFcn = createCallbackFcn(app, @UpconvertMenuSelected, true);
            app.UpconvertMenu.Text = 'Upconvert';
            app.IgnoreparticlesonborderMenu = uimenu(app.BinarisationMenu);
            app.IgnoreparticlesonborderMenu.MenuSelectedFcn = createCallbackFcn(app, @IgnoreparticlesonborderMenuSelected, true);
            app.IgnoreparticlesonborderMenu.Checked = 'on';
            app.IgnoreparticlesonborderMenu.Text = 'Ignore particles on border';
            app.AnalysisMenu = uimenu(app.SettingsMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.ApplyPCDnormarisationMenu = uimenu(app.AnalysisMenu);
            app.ApplyPCDnormarisationMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyPCDnormarisationMenuSelected, true);
            app.ApplyPCDnormarisationMenu.Checked = 'on';
            app.ApplyPCDnormarisationMenu.Text = 'Apply PCD normarisation';
            app.CalcRoundnessMenu = uimenu(app.AnalysisMenu);
            app.CalcRoundnessMenu.MenuSelectedFcn = createCallbackFcn(app, @CalcRoundnessMenuSelected, true);
            app.CalcRoundnessMenu.Checked = 'on';
            app.CalcRoundnessMenu.Text = 'Calc Roundness';
            app.ExportMenu = uimenu(app.SettingsMenu);
            app.ExportMenu.Text = 'Export';
            app.SavecsvMenu = uimenu(app.ExportMenu);
            app.SavecsvMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecsvMenuSelected, true);
            app.SavecsvMenu.Checked = 'on';
            app.SavecsvMenu.Text = 'Save csv';
            app.SaveBWimageMenu = uimenu(app.ExportMenu);
            app.SaveBWimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBWimageMenuSelected, true);
            app.SaveBWimageMenu.Checked = 'on';
            app.SaveBWimageMenu.Text = 'Save BW image';
            app.SavesummaryimageMenu = uimenu(app.ExportMenu);
            app.SavesummaryimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesummaryimageMenuSelected, true);
            app.SavesummaryimageMenu.Checked = 'on';
            app.SavesummaryimageMenu.Text = 'Save summary image';
            app.SavefittedimagewithNumberMenu = uimenu(app.ExportMenu);
            app.SavefittedimagewithNumberMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefittedimagewithNumberMenuSelected, true);
            app.SavefittedimagewithNumberMenu.Checked = 'on';
            app.SavefittedimagewithNumberMenu.Text = 'Save fitted image with Number';
            app.SavefittedimagewithRoundnessMenu = uimenu(app.ExportMenu);
            app.SavefittedimagewithRoundnessMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefittedimagewithRoundnessMenuSelected, true);
            app.SavefittedimagewithRoundnessMenu.Checked = 'on';
            app.SavefittedimagewithRoundnessMenu.Text = 'Save fitted image with Roundness';
            app.SavefittredimageasvectorMenu = uimenu(app.ExportMenu);
            app.SavefittredimageasvectorMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefittredimageasvectorMenuSelected, true);
            app.SavefittredimageasvectorMenu.Text = 'Save fittred image as vector';
            app.AutoSavesettingsMenu = uimenu(app.ExportMenu);
            app.AutoSavesettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoSavesettingsMenuSelected, true);
            app.AutoSavesettingsMenu.Text = 'Save settings';
            app.BatchMenu = uimenu(app.RgrainsUIFigure);
            app.BatchMenu.Text = 'Batch';
            app.RunBatch = uimenu(app.BatchMenu);
            app.RunBatch.MenuSelectedFcn = createCallbackFcn(app, @RunBatchMenuSelected, true);
            app.RunBatch.Text = 'Run from folder';
            app.ToolsMenu = uimenu(app.RgrainsUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.MergetableMenu = uimenu(app.ToolsMenu);
            app.MergetableMenu.MenuSelectedFcn = createCallbackFcn(app, @MergetableMenuSelected, true);
            app.MergetableMenu.Text = 'Merge table';
            app.OthersMenu = uimenu(app.RgrainsUIFigure);
            app.OthersMenu.Text = 'Others';
            app.VersionMenu = uimenu(app.OthersMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuSelected, true);
            app.VersionMenu.Text = 'Version';
            app.GridLayout = uigridlayout(app.RgrainsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NoiseThresholdLabel = uilabel(app.LeftPanel);
            app.NoiseThresholdLabel.Text = 'Noise thresholds [pix^2]';
            app.BinarisationLabel = uilabel(app.LeftPanel);
            app.BinarisationLabel.Text = '2. Binarisation';
            app.AnalysisLabel = uilabel(app.LeftPanel);
            app.AnalysisLabel.Text = '3. Analysis';
            app.ExportLabel = uilabel(app.LeftPanel);
            app.ExportLabel.Text = '4. Export';
            app.AcquisitionLabel = uilabel(app.LeftPanel);
            app.AcquisitionLabel.Text = '1. Acquisition';
            app.ParticlecolourDropDownLabel = uilabel(app.LeftPanel);
            app.ParticlecolourDropDownLabel.Text = 'Particle colour';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.SensitivityEditFieldLabel = uilabel(app.LeftPanel);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.ImagescalepixcmEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagescalepixcmEditFieldLabel.Text = 'Image scale [pix/cm]';
            app.TraceprecisionLabel = uilabel(app.LeftPanel);
            app.TraceprecisionLabel.Text = 'Trace precision';
            app.CornersensitivityEditFieldLabel = uilabel(app.LeftPanel);
            app.CornersensitivityEditFieldLabel.Text = 'Corner sensitivity';
            app.CircleprecisionLabel = uilabel(app.LeftPanel);
            app.CircleprecisionLabel.Text = 'Circle precision';
            app.PCDthresholdpixEditFieldLabel = uilabel(app.LeftPanel);
            app.PCDthresholdpixEditFieldLabel.Text = 'PCD threshold [pix]';
            app.PCDthreshold = uieditfield(app.LeftPanel, 'numeric');
            app.PCDthreshold.Limits = [0 Inf];
            app.PCDthreshold.ValueDisplayFormat = '%.0f';
            app.PCDthreshold.Value = 300;
            app.Circlerecision = uieditfield(app.LeftPanel, 'numeric');
            app.Circlerecision.Limits = [0 1];
            app.Circlerecision.ValueDisplayFormat = '%.3f';
            app.Circlerecision.Value = 0.996;
            app.Cornersensitivity = uieditfield(app.LeftPanel, 'numeric');
            app.Cornersensitivity.Limits = [0 1];
            app.Cornersensitivity.ValueDisplayFormat = '%.3f';
            app.Cornersensitivity.Value = 0.017;
            app.Traceprecision = uieditfield(app.LeftPanel, 'numeric');
            app.Traceprecision.Limits = [0 1];
            app.Traceprecision.ValueDisplayFormat = '%.3f';
            app.Traceprecision.Value = 0.06;
            app.NoiseThresholdUpper = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseThresholdUpper.Limits = [0 Inf];
            app.NoiseThresholdUpper.ValueDisplayFormat = '%.0f';
            app.NoiseThresholdUpper.Value = Inf;
            app.NoiseThresholdLower = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseThresholdLower.Limits = [0 Inf];
            app.NoiseThresholdLower.ValueDisplayFormat = '%.0f';
            app.NoiseThresholdLower.Value = 490;
            app.ImageScale = uieditfield(app.LeftPanel, 'numeric');
            app.ImageScale.Limits = [0 Inf];
            app.ImageScale.ValueDisplayFormat = '%.1f';
            app.ImageScale.Value = 340;
            app.BinariseSensitivity = uieditfield(app.LeftPanel, 'numeric');
            app.BinariseSensitivity.Limits = [0 1];
            app.BinariseSensitivity.Value = 0.35;
            app.BinariseMethod = uidropdown(app.LeftPanel);
            app.BinariseMethod.Items = {'Adaptive', 'Otsu', 'None'};
            app.BinariseMethod.ValueChangedFcn = createCallbackFcn(app, @BinariseMethodValueChanged, true);
            app.BinariseMethod.Value = 'Adaptive';
            app.ParticleColour = uidropdown(app.LeftPanel);
            app.ParticleColour.Items = {'Dark', 'Bright'};
            app.ParticleColour.Value = 'Dark';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.AnalyseButton = uibutton(app.LeftPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.BinariseButton = uibutton(app.LeftPanel, 'push');
            app.BinariseButton.ButtonPushedFcn = createCallbackFcn(app, @BinariseButtonPushed, true);
            app.BinariseButton.Enable = 'off';
            app.BinariseButton.Text = 'Binarise';
            app.LoadimageButton = uibutton(app.LeftPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainAxes = uiaxes(app.RightPanel);
            app.MainAxes.XTick = [];
            app.MainAxes.YTick = [];
            app.ViewImage = uidropdown(app.RightPanel);
            app.ViewImage.Items = {'Original', 'BW', 'Results'};
            app.ViewImage.ValueChangedFcn = createCallbackFcn(app, @ViewImageValueChanged, true);
            app.ViewImage.Value = 'Original';
            app.CurrentImageLabel = uilabel(app.RightPanel);
            app.CurrentImageLabel.Text = '...';
            % Show the figure after all components are created
            app.RgrainsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rgrains

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RgrainsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RgrainsUIFigure)
        end
    end
end